classdef SignalFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        BPMLabel                   matlab.ui.control.Label
        BPM                        matlab.ui.control.Label
        HighFCEditField            matlab.ui.control.NumericEditField
        HighFCEditFieldLabel       matlab.ui.control.Label
        LowFCEditField             matlab.ui.control.NumericEditField
        LowFCEditFieldLabel        matlab.ui.control.Label
        RFEditField                matlab.ui.control.NumericEditField
        RFEditFieldLabel           matlab.ui.control.Label
        RSEditField                matlab.ui.control.NumericEditField
        RSLabel                    matlab.ui.control.Label
        FilterOrderEditField       matlab.ui.control.NumericEditField
        FilterOrderLabel           matlab.ui.control.Label
        FilterMethodDropDown       matlab.ui.control.DropDown
        FilterMethodDropDownLabel  matlab.ui.control.Label
        FilterTypeDropDown         matlab.ui.control.DropDown
        FilterTypeDropDownLabel    matlab.ui.control.Label
        ArduinoPortDropDown        matlab.ui.control.DropDown
        ArduinoPortDropDownLabel   matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        RightPanel                 matlab.ui.container.Panel
        fourierGraph               matlab.ui.control.UIAxes
        filteredSignalGraph        matlab.ui.control.UIAxes
        orginalSignalGraph         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        isOpen = "Off";
        connected = 'false';

        time = 0;
        count = 0; % Data count
        data; % Data array
        initialDelay = 10;
        a = [1, -2.49860834469118, 2.11525412700316, -0.604109699507275];
        b;
        fs = 500;

        yMax = 5; % Maximum y-axis value
        yMin = -5; % Minimum y-axis value

        ardn;

        orginalGraph;
        filteredGraph;
        fftGraph;
        
        filterOrder = 2;
        filterMethod = 'Butter';
        filterType = 'Highpass';
        rs = 2;
        rf = 2;
        lowFc = 2;
        highFc = 2;
    end
    methods (Access = private)


        function results = start(app)
            warning('off', 'all');
            app.count = 0;
            app.data = [];
            app.time = [];

            % Initialize the plot on the app's graphs
            app.orginalGraph = plot(app.orginalSignalGraph, 0, 0, 'r');
            app.filteredGraph = plot(app.filteredSignalGraph, 0, 0, 'k');
            app.fftGraph = plot(app.fourierGraph, 0, 0, 'b');

            % Set the filter settings
            app.setFilterSettings();
            tic
            while strcmp(app.isOpen, 'Start')
                
                % Read the voltage
                voltage = readVoltage(app.ardn, 'A3');
                app.count = app.count + 1;
                disp(voltage);
                % Calculate the time of reading voltage
                app.time(app.count) = toc;
                app.data(app.count) = (voltage(1)); % voltage(1) * 0.2 / 115200
                
                set(app.orginalGraph, 'XData', app.time, 'YData', normalize(app.data));
                if mod(app.count, 1) == 0
                    axis(app.orginalSignalGraph, [app.time(app.count)-20 app.time(app.count) app.yMin app.yMax]);
                end

                % Comply the filter
                if app.count > app.initialDelay
                    filteredSignalArray = app.data(app.count-1);
                    disp('filteredSignalArray: ');
                    disp(filteredSignalArray);
                    %filteredData = (comp.data + filteredGraphData) / 2;
                    % disp('filteredData: ');
                    % disp(filteredData);
                    filteredSignal = filter(app.b, app.a, app.data);
                    disp('filteredSignal: ');
                    disp(filteredSignal);

                    % Update the filtered graph
                    set(app.filteredGraph, 'XData', app.time, 'YData', normalize(filteredSignal));
                    if mod(app.count, 1) == 0
                        axis(app.filteredSignalGraph, [app.time(app.count)-20 app.time(app.count) app.yMin app.yMax]);
                    end


                    % Calculate and update FFT plot
                    xdft = fft(filteredSignal);
                    xdft = xdft(1:length(filteredSignal)/2+1);
                    DF = app.fs/length(filteredSignal); % frequency increment
                    freqvec = 0:DF:app.fs/2;
                    
                    set(app.fftGraph, 'XData', freqvec, 'YData', abs(xdft));

                    %Detect peaks in the filtred signal
                    [~, locs] = findpeaks(app.data, 'MinPeakHeight', 0, 'MinPeakDistance', 0.8);
                     %Calculate heart rate (BPM)
                     locs_lenght=length(locs);
                     %disp(['Time Array:',num2str(comp.time)]);
                     disp(['lenght of comp.time:',num2str(length(app.time))]);
                     disp(['Number of peaks',num2str(locs_lenght)]);


                     if locs_lenght>1
                        %Calculate time in seconds between first and last peak
                        timeBetweenPeaks= app.time(locs(end))-app.time(locs(1));
                        %calculate heart rate 
                        beats =60*(locs_lenght-1)/timeBetweenPeaks;
                        %Update the BPM in the UI
                        app.BPM.Text=num2str(round(beats));
                     end


                end

                % Update the plot
                drawnow ; % Add this to update the plot immediately
                pause(0.01);
            end
            % app work stop

        end
        
        function results = setFilterSettings(app)
             % Decision making
                switch app.FilterMethodDropDown.Value
                    case 'Butter'
                        switch app.FilterTypeDropDown.Value
                            case 'Lowpass'
                                [app.b, app.a] = butter(4, 0.5, 'low');
                            case 'Highpass'
                                [app.b, app.a] = butter(str2double(app.filterOrder), str2double(app.lowFc)/(app.fs/2), "high");
                            case 'Bandstop'
                                [app.b, app.a] = butter(str2double(app.filterOrder), [str2double(app.lowFc)/(app.fs/2) app.highFc/(app.fs/2)], "stop");
                            case 'Bandpass'
                                [app.b, app.a] = butter(str2double(app.filterOrder), [str2double(app.lowFc)/(app.fs/2) app.highFc/(app.fs/2)], "bandpass");
                        end
                    case 'Elliptic'
                        switch app.filterType
                            case 'Lowpass'
                                [app.b, app.a] = ellip(str2double(app.filterOrder), app.rp.Value, app.rs.Value, str2double(app.lowFc)/(app.fs/2), 'low');
                            case 'Highpass'
                                [app.b, app.a] = ellip(str2double(app.filterOrder), app.rp.Value, app.rs.Value, str2double(app.lowFc)/(app.fs/2), 'high');
                            case 'Bandstop'
                                [app.b, app.a] = ellip(str2double(app.filterOrder), app.rp.Value, app.rs.Value, [str2double(app.lowFc)/(app.fs/2) app.highFc/(app.fs/2)], 'stop');
                            case 'Bandpass'
                                [app.b, app.a] = ellip(str2double(app.filterOrder), app.rp.Value, app.rs.Value, [str2double(app.lowFc)/(app.fs/2) app.highFc/(app.fs/2)], 'bandpass');
                        end
                end
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.isOpen = value;
            if strcmp(app.isOpen, 'Start') && strcmp(app.connected, 'true') 
                app.Lamp.Color = [0, 1, 0];
                app.start();
            else 
                app.Lamp.Color = [0.85, 0.33, 0.10];
            end
            
        end

        % Value changed function: ArduinoPortDropDown
        function ArduinoPortDropDownValueChanged(app, event)
            value = app.ArduinoPortDropDown.Value;
            try
                app.ardn = arduino(value, 'Uno');
                app.Lamp.Color = [0, 1, 1];
                app.connected = 'true';
                disp('bağlandı');
            catch
                app.connected = 'false';
                app.Lamp.Color = [1, 0, 1];
                disp('bağlantı hatası');
            end
        end

        % Value changed function: FilterMethodDropDown
        function FilterMethodDropDownValueChanged(app, event)
            value = app.FilterMethodDropDown.Value;
            app.filterMethod = value;
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            app.filterType = value;
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;

            app.filterOrder = value;
        end

        % Value changed function: RSEditField
        function RSEditFieldValueChanged(app, event)
            value = app.RSEditField.Value;
            app.rs = value;
        end

        % Value changed function: RFEditField
        function RFEditFieldValueChanged(app, event)
            value = app.RFEditField.Value;
            app.rf = value;
        end

        % Value changed function: LowFCEditField
        function LowFCEditFieldValueChanged(app, event)
            value = app.LowFCEditField.Value;
            app.lowFc = value;
        end

        % Value changed function: HighFCEditField
        function HighFCEditFieldValueChanged(app, event)
            value = app.HighFCEditField.Value;
            app.highFc = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {634, 634};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'Stop', 'Start'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.LampLabel = uilabel(app.LeftPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.LeftPanel);
            app.ArduinoPortDropDownLabel = uilabel(app.LeftPanel);
            app.ArduinoPortDropDownLabel.Text = 'Arduino Port';
            app.ArduinoPortDropDown = uidropdown(app.LeftPanel);
            app.ArduinoPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.ArduinoPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ArduinoPortDropDownValueChanged, true);
            app.ArduinoPortDropDown.Value = 'COM3';
            app.FilterTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type:';
            app.FilterTypeDropDown = uidropdown(app.LeftPanel);
            app.FilterTypeDropDown.Items = {'Highpass', 'Lowpass', 'Bandpass', 'Bandstop'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Lowpass';
            app.FilterMethodDropDownLabel = uilabel(app.LeftPanel);
            app.FilterMethodDropDownLabel.Text = 'Filter Method: ';
            app.FilterMethodDropDown = uidropdown(app.LeftPanel);
            app.FilterMethodDropDown.Items = {'Butter', 'Elliptic'};
            app.FilterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterMethodDropDownValueChanged, true);
            app.FilterMethodDropDown.Value = 'Butter';
            app.FilterOrderLabel = uilabel(app.LeftPanel);
            app.FilterOrderLabel.Text = 'Filter Order:';
            app.FilterOrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilterOrderEditField.Limits = [0 20];
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 2;
            app.RSLabel = uilabel(app.LeftPanel);
            app.RSLabel.Text = 'RS:';
            app.RSEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RSEditField.Limits = [0 100];
            app.RSEditField.ValueChangedFcn = createCallbackFcn(app, @RSEditFieldValueChanged, true);
            app.RSEditField.Value = 2;
            app.RFEditFieldLabel = uilabel(app.LeftPanel);
            app.RFEditFieldLabel.Text = 'RF:';
            app.RFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RFEditField.Limits = [0 100];
            app.RFEditField.ValueChangedFcn = createCallbackFcn(app, @RFEditFieldValueChanged, true);
            app.RFEditField.Value = 2;
            app.LowFCEditFieldLabel = uilabel(app.LeftPanel);
            app.LowFCEditFieldLabel.Text = 'Low FC:';
            app.LowFCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowFCEditField.Limits = [0 100];
            app.LowFCEditField.ValueChangedFcn = createCallbackFcn(app, @LowFCEditFieldValueChanged, true);
            app.LowFCEditField.Value = 2;
            app.HighFCEditFieldLabel = uilabel(app.LeftPanel);
            app.HighFCEditFieldLabel.Text = 'High FC:';
            app.HighFCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HighFCEditField.Limits = [0 100];
            app.HighFCEditField.ValueChangedFcn = createCallbackFcn(app, @HighFCEditFieldValueChanged, true);
            app.HighFCEditField.Value = 2;
            app.BPM = uilabel(app.LeftPanel);
            app.BPM.Text = '---';
            app.BPMLabel = uilabel(app.LeftPanel);
            app.BPMLabel.Text = 'BPM:';
            app.RightPanel = uipanel(app.GridLayout);
            app.orginalSignalGraph = uiaxes(app.RightPanel);
            title(app.orginalSignalGraph, 'Orginal Signal')
            xlabel(app.orginalSignalGraph, 'X')
            ylabel(app.orginalSignalGraph, 'Y')
            zlabel(app.orginalSignalGraph, 'Z')
            app.filteredSignalGraph = uiaxes(app.RightPanel);
            title(app.filteredSignalGraph, 'Filtered Signal')
            xlabel(app.filteredSignalGraph, 'X')
            ylabel(app.filteredSignalGraph, 'Y')
            zlabel(app.filteredSignalGraph, 'Z')
            app.fourierGraph = uiaxes(app.RightPanel);
            title(app.fourierGraph, 'Fourier')
            xlabel(app.fourierGraph, 'X')
            ylabel(app.fourierGraph, 'Y')
            zlabel(app.fourierGraph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end